classdef X_sigma_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        X_1PriceSliderLabel  matlab.ui.control.Label
        X_1PriceSlider       matlab.ui.control.Slider
        X_2PriceSliderLabel  matlab.ui.control.Label
        X_2PriceSlider       matlab.ui.control.Slider
        SigmaLabel           matlab.ui.control.Label
        SigmaSlider          matlab.ui.control.Slider
    end


    methods (Access = private)

        % Value changed function: SigmaSlider, X_1PriceSlider, 
        % X_2PriceSlider
        function Slider_Changed(app, event)
             
            %% Parameters
            
            alpha = [1, 2];
            xi_1  = [1, 1];
            xi_2  = [0.5, 1.5];
            
            sigma = 0.5;
            phi   = (sigma - 1)/sigma;
            budget = 1;
            
            x_1_cost_param = 1;
            x_2_cost_param = 1.1;
            
            %% Slider Updates
            x_1_cost_param = app.X_1PriceSlider.Value;
            x_2_cost_param = app.X_2PriceSlider.Value;
            sigma = app.SigmaSlider.Value;
            phi   = (sigma - 1)/sigma;
            
            
            %% Solution
            
            % Prices
            xi_mat   = [xi_1; xi_2];
            cost_mat = [x_1_cost_param; x_2_cost_param];
            prices   = xi_mat\cost_mat;
            
            % Price Index
            P = ((1/2) * (prices'.^(1-sigma))*(alpha'.^sigma)).^(1/(1-sigma));
            
            % Quantities
            Y = ((alpha'./prices).^(sigma)) * (budget/P);
            X = (xi_mat')\Y;
            
            h = bar(app.UIAxes, X, ...
                'FaceAlpha', 0.7);
            grid(app.UIAxes, 'on')
            title(app.UIAxes, 'Use of Each Technology')
            xlabel(app.UIAxes, 'Technology')
            ylabel(app.UIAxes, 'Quantity')
            
            app.UIAxes.YLim = [0, 5];
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.X_1PriceSliderLabel = uilabel(app.UIFigure);
            app.X_1PriceSliderLabel.Text = 'X_1 Price';
            app.X_1PriceSlider = uislider(app.UIFigure);
            app.X_1PriceSlider.Limits = [0 2];
            app.X_1PriceSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.X_1PriceSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.X_1PriceSlider.MinorTicks = [];
            app.X_1PriceSlider.Value = 1;
            app.X_2PriceSliderLabel = uilabel(app.UIFigure);
            app.X_2PriceSliderLabel.Text = 'X_2 Price';
            app.X_2PriceSlider = uislider(app.UIFigure);
            app.X_2PriceSlider.Limits = [0 2];
            app.X_2PriceSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.X_2PriceSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.X_2PriceSlider.MinorTicks = [];
            app.X_2PriceSlider.Value = 1;
            app.SigmaLabel = uilabel(app.UIFigure);
            app.SigmaLabel.Text = 'Sigma';
            app.SigmaSlider = uislider(app.UIFigure);
            app.SigmaSlider.Limits = [0 1];
            app.SigmaSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SigmaSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.SigmaSlider.MinorTicks = [];
            app.SigmaSlider.Value = 0.5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = X_sigma_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end