classdef log_X_diff_sigma < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        SigmaLabel   matlab.ui.control.Label
        SigmaSlider  matlab.ui.control.Slider
    end


    methods (Access = private)

        % Value changed function: SigmaSlider
        function Slider_Changed(app, event)
             
            n = 500;
            results = zeros(n,2); 
            cost_multiplier = linspace(0.5,1.5,n);
            
            for i = 1:n
                
                alpha = [0.3, 0.7];
                xi_1  = [1,   1];
                xi_2  = [0.1, 1];
            
                sigma = app.SigmaSlider.Value;;
                phi   = (sigma - 1)/sigma;
                budget = 1;
            
                x_1_cost_param = 95*cost_multiplier(i);
                x_2_cost_param = 50;
            
                % Prices
                xi_mat   = [xi_1; xi_2];
                cost_mat = [x_1_cost_param; x_2_cost_param];
                prices   = xi_mat\cost_mat;
                
                if any(prices<0)
                    continue
                end
                
                % Price Index
                P = ((1/2) * (prices'.^(1-sigma))*(alpha'.^sigma)).^(1/(1-sigma));
            
                % Quantities
                Y = ((alpha'./prices).^(sigma)) * (budget/P);
                X = (xi_mat')\Y;
                
                results(i,:) = X';
                
            end    
                
            output = [];
            output(1,:) = 95*cost_multiplier'./50;
            output(2,:) = results(:,1)./results(:,2);
            
            % subset to positive quantities
            ind = ~any(output <= 0);
            output = output(:,ind);
            
            % relationship between log prices and quantities
            h = plot(app.UIAxes, log(output(2,:)), log(output(1,:)));
            grid(app.UIAxes, 'on')
            title(app.UIAxes, 'Use of Each Technology')
            xlabel(app.UIAxes, 'Log Difference in Quantities')
            ylabel(app.UIAxes, 'Log Difference in Prices')
            
            app.UIAxes.YLim = [0.2, 1.2];
            app.UIAxes.XLim = [-5, 7];
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SigmaLabel = uilabel(app.UIFigure);
            app.SigmaLabel.Text = 'Sigma';
            app.SigmaSlider = uislider(app.UIFigure);
            app.SigmaSlider.Limits = [0 2];
            app.SigmaSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.SigmaSlider.MinorTicks = [];
            app.SigmaSlider.Value = 0.5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = log_X_diff_sigma

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end