classdef input_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        X_1PriceSliderLabel  matlab.ui.control.Label
        X_1PriceSlider       matlab.ui.control.Slider
        X_2PriceSliderLabel  matlab.ui.control.Label
        X_2PriceSlider       matlab.ui.control.Slider
        PhiSliderLabel       matlab.ui.control.Label
        PhiSlider            matlab.ui.control.Slider
    end


    methods (Access = private)

        % Value changed function: PhiSlider, X_1PriceSlider, 
        % X_2PriceSlider
        function Slider_Changed(app, event)
            
            parameters.alpha = @(t) 1.75-(2.*t-1).^2/2;
            parameters.xi_1  = @(t) cos(t.*3-2)/2 + 1.5;
            parameters.xi_2  = @(t) sin(t.*6-.5)/2 + 1.5;
            
            parameters.phi =  2;
            parameters.budget = 1;
            
            parameters.x_1_cost_param = app.X_1PriceSlider.Value;
            parameters.x_2_cost_param = app.X_2PriceSlider.Value;
            parameters.phi = app.PhiSlider.Value;
            
            [ optimal_vars, optimal_val, objfunc, objcons ] = optimize ( parameters );
            
            % unpack params
            price_coeffs = optimal_vars;
            
            alpha = parameters.alpha;
            xi_1  = parameters.xi_1;
            xi_2  = parameters.xi_2;
            
            x_1_cost_param = parameters.x_1_cost_param;
            x_2_cost_param = parameters.x_2_cost_param;
            
            phi   = parameters.phi;
            sigma = 1 / (1-phi);
            
            % 
            price_coeffs_positive = sum(price_coeffs > 0) == length(price_coeffs);
            
            X_1 = (1/x_1_cost_param)*xi_1(linspace(0,1,24))*price_coeffs'/24;
            X_2 = (1/x_2_cost_param)*xi_2(linspace(0,1,24))*price_coeffs'/24;
            
            X_1_energy = (1/24)*(xi_1(linspace(0,1,24))*X_1);
            X_2_energy = (1/24)*(xi_2(linspace(0,1,24))*X_2);
            
            h = area(app.UIAxes, linspace(0,1, 24), [X_1_energy', X_2_energy'], ...
                'FaceAlpha', 0.7);
            grid(app.UIAxes, 'on')
            title(app.UIAxes, 'Energy Ouput over Time')
            xlabel(app.UIAxes, 'Energy Output')
            ylabel(app.UIAxes, 'Time')
            
            app.UIAxes.YLim = [0, 0.75];
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.X_1PriceSliderLabel = uilabel(app.UIFigure);
            app.X_1PriceSliderLabel.Text = 'X_1 Price';
            app.X_1PriceSlider = uislider(app.UIFigure);
            app.X_1PriceSlider.Limits = [0 1];
            app.X_1PriceSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.X_1PriceSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.X_1PriceSlider.Value = 0.5;
            app.X_2PriceSliderLabel = uilabel(app.UIFigure);
            app.X_2PriceSliderLabel.Text = 'X_2 Price';
            app.X_2PriceSlider = uislider(app.UIFigure);
            app.X_2PriceSlider.Limits = [0 1];
            app.X_2PriceSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.X_2PriceSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.X_2PriceSlider.Value = 0.5;
            app.PhiSliderLabel = uilabel(app.UIFigure);
            app.PhiSliderLabel.Text = 'Phi';
            app.PhiSlider = uislider(app.UIFigure);
            app.PhiSlider.Limits = [1 3];
            app.PhiSlider.MajorTicks = [1 1.25 1.5 1.75 2 2.25 2.5 2.75 3];
            app.PhiSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_Changed, true);
            app.PhiSlider.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = input_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end